classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AxisSelectionsPanel       matlab.ui.container.Panel
        XAxisDropDownLabel        matlab.ui.control.Label
        XAxisDropDown             matlab.ui.control.DropDown
        YAxisDropDownLabel        matlab.ui.control.Label
        YAxisDropDown             matlab.ui.control.DropDown
        TabGroup                  matlab.ui.container.TabGroup
        PlotTab                   matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        DataTab                   matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        MetadataTab               matlab.ui.container.Tab
        UITable2                  matlab.ui.control.Table
        LiveCellsOnlySwitchLabel  matlab.ui.control.Label
        LiveCellsOnlySwitch       matlab.ui.control.Switch
        MetaDataDropDownLabel     matlab.ui.control.Label
        MetaDataDropDown          matlab.ui.control.DropDown
        Gating1EditFieldLabel     matlab.ui.control.Label
        Gating1EditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Data % table that holds all quantitative data
        metaData % table that holds all non-numerical data
        x_data % contains the column selected for x-axis
        y_data % contains the column selected for y-axis
        row_index % property that keeps track of which row index will be plotted
        
        
    end
    
    methods (Access = private)
        
        % plots data based on most recent dropdown values
        function refreshPlot(app)
            
            % ensures columns to be plotted are up-to-date with dropdown selections 
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            % graph formatting
            app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            app.UIAxes.XLabel.String = app.XAxisDropDown.Value;
            app.UIAxes.YLabel.String = app.YAxisDropDown.Value;
            
            scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index))
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            % THINK ABOUT LOADING THE DATA IN AFTER THE APP HAS OPENED. RN
            % YOU HAVE TO LOAD IN DATA BEFORE THE APP INITIALIZES
            
            % Loads in Data
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the data file.'});
            DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
            colNames = DensityRead.Properties.VariableNames;
            % app.XAxisDropDown.Items = DensityRead.Properties.VariableNames
            % app.YAxisDropDown.Items = DensityRead.Properties.VariableNames
                    
            app.Data = DensityRead;
            app.UITable.Data = app.Data; % ultimately, the data tab is optional as it will not display the entirety of the data once there are >2 pmts
            
            
            % loads axis dropdown options based on column names from DensityRead
            [nr,nc] = size(app.Data);
            for i = 2:nc
                app.XAxisDropDown.Items(i-1) = colNames(i); 
                app.YAxisDropDown.Items(i-1) = colNames(i); 
            end            
            
                                  
            % Loads in MetaData
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the data file.'});
            DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
            colNames = DensityRead.Properties.VariableNames;
            
            app.metaData = DensityRead;
            app.UITable2.Data = app.metaData;
            
            % loads metadata dropdown options based on DensityRead
            [nr,nc] = size(app.metaData);
            for i = 2:nc
                app.MetaDataDropDown.Items(i-1) = colNames(i); 
            end
            
                        
            % initializes index property
            app.row_index = 1:height(app.Data);
            
            
            refreshPlot(app)
            
            
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: LiveCellsOnlySwitch
        function LiveCellsOnlySwitchValueChanged(app, event)
            
            value = app.LiveCellsOnlySwitch.Value;
            if value == "On"              
                app.row_index = app.metaData{:,5} == "live";
            
            else 
                app.row_index = 1:height(app.Data);
            end
            
            refreshPlot(app)
            
        end

        % Value changed function: Gating1EditField
        function Gating1EditFieldValueChanged(app, event)
            value = app.Gating1EditField.Value;
            gating = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'item1';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'item1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.UITable2 = uitable(app.MetadataTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.LiveCellsOnlySwitchLabel = uilabel(app.UIFigure);
            app.LiveCellsOnlySwitchLabel.Text = 'Live Cells Only?';
            app.LiveCellsOnlySwitch = uiswitch(app.UIFigure, 'slider');
            app.LiveCellsOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @LiveCellsOnlySwitchValueChanged, true);
            app.MetaDataDropDownLabel = uilabel(app.UIFigure);
            app.MetaDataDropDownLabel.Text = 'Meta Data';
            app.MetaDataDropDown = uidropdown(app.UIFigure);
            app.MetaDataDropDown.Items = {'Item1', 'Item2', 'Item3', 'Item4'};
            app.MetaDataDropDown.Value = 'Item1';
            app.Gating1EditFieldLabel = uilabel(app.UIFigure);
            app.Gating1EditFieldLabel.Text = 'Gating1';
            app.Gating1EditField = uieditfield(app.UIFigure, 'text');
            app.Gating1EditField.ValueChangedFcn = createCallbackFcn(app, @Gating1EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end